classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MAINTab                         matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        PredictionanddetectionofepilepticseizuresLabel_3  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        INFOButton                      matlab.ui.control.Button
        Label_blue_27                   matlab.ui.control.Label
        ComputaoNeuronaleSistemasDifusos202021Label_5  matlab.ui.control.Label
        PL4Grupo3AndrBernardesJoanaBaioLabel_6  matlab.ui.control.Label
        Train                           matlab.ui.container.Tab
        Label_blue_4                    matlab.ui.control.Label
        Label_blue_29                   matlab.ui.control.Label
        Label_blue_30                   matlab.ui.control.Label
        Label_blue_2                    matlab.ui.control.Label
        Label_blue_28                   matlab.ui.control.Label
        Label_blue_3                    matlab.ui.control.Label
        TrainnetworkButton              matlab.ui.control.Button
        Label_blue_7                    matlab.ui.control.Label
        Label_blue_8                    matlab.ui.control.Label
        Label_blue_9                    matlab.ui.control.Label
        PatientIDDropDownLabel          matlab.ui.control.Label
        PatientIDDropDown               matlab.ui.control.DropDown
        NetworktypeDropDownLabel        matlab.ui.control.Label
        NetworktypeDropDown             matlab.ui.control.DropDown
        FeaturesDropDownLabel           matlab.ui.control.Label
        FeaturesDropDown                matlab.ui.control.DropDown
        OptimizationDropDownLabel       matlab.ui.control.Label
        OptimizationDropDown            matlab.ui.control.DropDown
        TrainingstyleLabel              matlab.ui.control.Label
        TrainingstyleDropDown           matlab.ui.control.DropDown
        ActivationfunctionLabel         matlab.ui.control.Label
        ActivationfunctionDropDown      matlab.ui.control.DropDown
        NetworkarchitectureLabel        matlab.ui.control.Label
        NetworkarchitectureDropDown     matlab.ui.control.DropDown
        NoofhiddenlayersSpinnerLabel    matlab.ui.control.Label
        NoofhiddenlayersSpinner         matlab.ui.control.Spinner
        LearningfunctionLabel           matlab.ui.control.Label
        LearningfunctionDropDown        matlab.ui.control.DropDown
        TrainfunctionDropDownLabel      matlab.ui.control.Label
        TrainfunctionDropDown           matlab.ui.control.DropDown
        Label_blue_10                   matlab.ui.control.Label
        SolverDropDown_3Label           matlab.ui.control.Label
        SolverDropDown_3                matlab.ui.control.DropDown
        NeuronsEditField_2Label         matlab.ui.control.Label
        NeuronsEditField_2              matlab.ui.control.EditField
        NeuronsEditField_3Label         matlab.ui.control.Label
        NeuronsEditField_3              matlab.ui.control.EditField
        HelpButton                      matlab.ui.control.Button
        EpochsEditFieldLabel            matlab.ui.control.Label
        EpochsEditField                 matlab.ui.control.EditField
        EpochsEditField_2Label          matlab.ui.control.Label
        EpochsEditField_2               matlab.ui.control.EditField
        NooffiltersineachlayerEditFieldLabel  matlab.ui.control.Label
        NooffiltersineachlayerEditField  matlab.ui.control.EditField
        SizeoffiltersforeachlayerEditFieldLabel  matlab.ui.control.Label
        SizeoffiltersforeachlayerEditField  matlab.ui.control.EditField
        LayersDelays1nLabel             matlab.ui.control.Label
        LayersDelays1nEditField         matlab.ui.control.EditField
        HelpButton_2                    matlab.ui.control.Button
        PreprocessingstepDropDownLabel  matlab.ui.control.Label
        PreprocessingstepDropDown       matlab.ui.control.DropDown
        HelpButton_4                    matlab.ui.control.Button
        Label_blue_5                    matlab.ui.control.Label
        SolverDropDown_2Label           matlab.ui.control.Label
        SolverDropDown_2                matlab.ui.control.DropDown
        NoofLayersSpinnerLabel          matlab.ui.control.Label
        NoofLayersSpinner               matlab.ui.control.Spinner
        PoolingtypeDropDownLabel        matlab.ui.control.Label
        PoolingtypeDropDown             matlab.ui.control.DropDown
        HIDDENLAYERSLabel               matlab.ui.control.Label
        CONVOLUTIONALLAYERSLabel        matlab.ui.control.Label
        StrideforeachlayerEditFieldLabel  matlab.ui.control.Label
        StrideforeachlayerEditField     matlab.ui.control.EditField
        POOLINGLAYERSLabel              matlab.ui.control.Label
        SizeforeachlayerLabel           matlab.ui.control.Label
        SizeforeachlayerEditField       matlab.ui.control.EditField
        StrideforeachlayerEditField_2Label  matlab.ui.control.Label
        StrideforeachlayerEditField_2   matlab.ui.control.EditField
        HelpButton_5                    matlab.ui.control.Button
        Label_7                         matlab.ui.control.Label
        PredictionanddetectionofepilepticseizuresLabel_6  matlab.ui.control.Label
        TESTTab                         matlab.ui.container.Tab
        Label_blue_11                   matlab.ui.control.Label
        Label_blue_12                   matlab.ui.control.Label
        SearchButton                    matlab.ui.control.Button
        TestnetworkButton               matlab.ui.control.Button
        Label_blue_13                   matlab.ui.control.Label
        Label_blue_14                   matlab.ui.control.Label
        PREDICTIONLabel                 matlab.ui.control.Label
        DETECTIONLabel                  matlab.ui.control.Label
        SensitivityEditFieldLabel       matlab.ui.control.Label
        SensitivityEditField            matlab.ui.control.NumericEditField
        Label_blue_16                   matlab.ui.control.Label
        Label_blue_17                   matlab.ui.control.Label
        Label_blue_18                   matlab.ui.control.Label
        PREDICTIONLabel_2               matlab.ui.control.Label
        DETECTIONLabel_2                matlab.ui.control.Label
        Label_blue_19                   matlab.ui.control.Label
        SpecificityEditField_5Label     matlab.ui.control.Label
        SpecificityEditField_5          matlab.ui.control.NumericEditField
        SensitivityEditField_2Label     matlab.ui.control.Label
        SensitivityEditField_2          matlab.ui.control.NumericEditField
        SpecificityEditField_6Label     matlab.ui.control.Label
        SpecificityEditField_6          matlab.ui.control.NumericEditField
        SensitivityEditField_3Label     matlab.ui.control.Label
        SensitivityEditField_3          matlab.ui.control.NumericEditField
        SpecificityEditField_7Label     matlab.ui.control.Label
        SpecificityEditField_7          matlab.ui.control.NumericEditField
        SensitivityEditField_4Label     matlab.ui.control.Label
        SensitivityEditField_4          matlab.ui.control.NumericEditField
        SpecificityEditField_8Label     matlab.ui.control.Label
        SpecificityEditField_8          matlab.ui.control.NumericEditField
        SelectednetworkLabel            matlab.ui.control.Label
        SelectednetworkEditField        matlab.ui.control.EditField
        Label_blue_21                   matlab.ui.control.Label
        Label_blue_22                   matlab.ui.control.Label
        NumberofseizuresEditFieldLabel  matlab.ui.control.Label
        NumberofseizuresEditField       matlab.ui.control.NumericEditField
        SeizurespredictedEditFieldLabel  matlab.ui.control.Label
        SeizurespredictedEditField      matlab.ui.control.NumericEditField
        SeizuresdetectedEditFieldLabel  matlab.ui.control.Label
        SeizuresdetectedEditField       matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        PredictionanddetectionofepilepticseizuresLabel_5  matlab.ui.control.Label
        CLUSTERINGTab                   matlab.ui.container.Tab
        GraphReducedFeatures            matlab.ui.control.UIAxes
        GraphClusters                   matlab.ui.control.UIAxes
        Label_5                         matlab.ui.control.Label
        PredictionanddetectionofepilepticseizuresLabel_4  matlab.ui.control.Label
        Label_blue_25                   matlab.ui.control.Label
        Label_blue_26                   matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        ClusteringalgorithmDropDownLabel  matlab.ui.control.Label
        ClusteringalgorithmDropDown     matlab.ui.control.DropDown
        PatientIDDropDown_2Label        matlab.ui.control.Label
        PatientIDDropDown_2             matlab.ui.control.DropDown
        EpsilonEditFieldLabel           matlab.ui.control.Label
        EpsilonEditField                matlab.ui.control.EditField
        MinimumPointsEditFieldLabel     matlab.ui.control.Label
        MinimumPointsEditField          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Property % Description
        
        % DEFAULTS - General Options
        patientID='44202';
        nn_type = 'LR';
        n_features = 29;
        optimization=0;
        
        % DEFAULTS - SNN
        delay=1;
        training_style='BT';
        training_option='traingd';
        activation_function='purelin';
        layers=1; %hidden layers caso SNN, layers caso DNN
        
        % DEFAULTS - DNN (CNN + LSTM)
        solver = 'adam';
        epochs = 10;
        neurons = 10;
        
        pooling = 'max';
        conv_nfilters = 3;
        conv_filtersize = 32;        
        conv_stride=1;
        pooling_size=2;
        pooling_stride=2;
        
        step = 29;     
                
        file_name = '';
        path = '';
        
        % CLUSTERING
        clustering_algorithm = "DBSCAN";
        min_points = 5;
        epsilon = 0.05;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PatientIDDropDown
        function PatientIDDropDownValueChanged(app, event)
            app.patientID = app.PatientIDDropDown.Value; % Paciente escolhido
        end

        % Value changed function: NetworktypeDropDown
        function NetworktypeDropDownValueChanged(app, event)
            value = app.NetworktypeDropDown.Value;
            
            % Grupo da SNN
            handleArray1 = [ app.LayersDelays1nEditField, app.LayersDelays1nLabel, app.TrainfunctionDropDown, ...
                app.TrainfunctionDropDownLabel, app.LearningfunctionDropDown, app.LearningfunctionLabel, ...
                app.NoofhiddenlayersSpinner, app.NoofhiddenlayersSpinnerLabel, app.NetworkarchitectureDropDown, ...
                app.NetworkarchitectureLabel, app.ActivationfunctionDropDown, app.ActivationfunctionLabel,...
                app.TrainingstyleDropDown, app.TrainingstyleLabel, app.Label_blue_3, app.NeuronsEditField_3, ...
                app.HelpButton, app.NeuronsEditField_3Label, app.HIDDENLAYERSLabel,app.OptimizationDropDownLabel, app.OptimizationDropDown];
             
            % Grupo da CNN
            handleArray2 = [app.PoolingtypeDropDown, app.PoolingtypeDropDownLabel, app.NoofLayersSpinner, ...
                app.NoofLayersSpinnerLabel, app.SolverDropDown_2, app.SolverDropDown_2Label, app.EpochsEditField,...
                app.EpochsEditFieldLabel, app.NooffiltersineachlayerEditFieldLabel, app.NooffiltersineachlayerEditField, app.SizeoffiltersforeachlayerEditField,...
                app.SizeoffiltersforeachlayerEditFieldLabel, app.Label_blue_5, app.HelpButton_2, ...
                app.PreprocessingstepDropDown, app.PreprocessingstepDropDownLabel,app.HelpButton_4, ...
                app.Label_blue_29, app.StrideforeachlayerEditField_2, app.StrideforeachlayerEditField_2Label,...
                app.SizeforeachlayerEditField, app.SizeforeachlayerLabel, app.POOLINGLAYERSLabel,...
                app.StrideforeachlayerEditField, app.StrideforeachlayerEditFieldLabel, app.CONVOLUTIONALLAYERSLabel, ...
                app.HelpButton_5];
            
            % Grupo LSTM
            handleArray3 = [app.EpochsEditField_2, app.EpochsEditField_2Label, app.NeuronsEditField_2, ...
                app.NeuronsEditField_2Label, app.SolverDropDown_3, app.SolverDropDown_3Label, app.Label_blue_7];
            
            handleArrayF =[app.FeaturesDropDown, app.FeaturesDropDownLabel];
            
            if isequal(value, 'SNN')
                
                set(handleArray1, 'Enable', 'on');
                set(handleArray2, 'Enable', 'off');
                set(handleArray3, 'Enable', 'off');
                set(handleArrayF, 'Enable', 'on');
                
            elseif isequal(value, 'DNN: CNN')
                app.nn_type = 'CNN';
                
                set(handleArray1, 'Enable', 'off');
                set(handleArray2, 'Enable', 'on');
                set(handleArray3, 'Enable', 'off');
                set(handleArrayF, 'Enable', 'off');
                
            elseif isequal(value, 'DNN: LSTM')
                app.nn_type = 'LSTM';
                
                set(handleArray1, 'Enable', 'off');
                set(handleArray2, 'Enable', 'off');
                set(handleArray3, 'Enable', 'on');
                set(handleArrayF, 'Enable', 'on');
                
            end
            
        end

        % Value changed function: FeaturesDropDown
        function FeaturesDropDownValueChanged(app, event)
            app.n_features = str2num(app.FeaturesDropDown.Value);
            
        end

        % Value changed function: OptimizationDropDown
        function OptimizationDropDownValueChanged(app, event)
            value = app.OptimizationDropDown.Value;
            
            if isequal(value, 'None')
                app.optimization=0;
                
            elseif isequal(value, 'Parallel')
                app.optimization=1;
                
            elseif isequal(value, 'GPU')
                app.optimization=2;
                
            elseif isequal(value, 'GPU + Parallel')
                app.optimization=3;
                
            end
        end

        % Value changed function: TrainingstyleDropDown
        function TrainingstyleDropDownValueChanged(app, event)
            value = app.TrainingstyleDropDown.Value;
            
            handleArrayBT=[app.TrainfunctionDropDown, app.TrainfunctionDropDownLabel];
            handleArrayIL=[app.LearningfunctionDropDown, app.LearningfunctionLabel];
            
            if isequal(value, 'Batch training')
                app.training_style = 'BT';
                
                set(handleArrayBT, 'Visible', 'on');
                set(handleArrayIL, 'Visible', 'off');
                
            elseif isequal(value, 'Incremental learning')
                app.training_style = 'BT';
                
                set(handleArrayBT, 'Visible', 'off');
                set(handleArrayIL, 'Visible', 'on');
                
            end
            
        end

        % Value changed function: LearningfunctionDropDown
        function LearningfunctionDropDownValueChanged(app, event)
            app.training_option = app.LearningfunctionDropDown.Value;
            
        end

        % Value changed function: TrainfunctionDropDown
        function TrainfunctionDropDownValueChanged(app, event)
            app.training_option = app.TrainfunctionDropDown.Value;
            
        end

        % Value changed function: NetworkarchitectureDropDown
        function NetworkarchitectureDropDownValueChanged(app, event)
            value = app.NetworkarchitectureDropDown.Value;
            
            handleArrayDelays=[app.LayersDelays1nEditField, app.LayersDelays1nLabel];
            
            if isequal(value, 'Recurrent')
                app.nn_type = 'LR';
                
                set(handleArrayDelays, 'Visible', 'on');
                                
            elseif isequal(value, 'Feedforward')
                app.nn_type = 'FF';
                
                set(handleArrayDelays, 'Visible', 'off');
                
            end
        end

        % Value changed function: ActivationfunctionDropDown
        function ActivationfunctionDropDownValueChanged(app, event)
            app.activation_function = app.ActivationfunctionDropDown.Value;
            
        end

        % Value changed function: NoofhiddenlayersSpinner
        function NoofhiddenlayersSpinnerValueChanged(app, event)
            app.layers = app.NoofhiddenlayersSpinner.Value;
            
        end

        % Value changed function: SolverDropDown_2
        function SolverDropDown_2ValueChanged(app, event)
            app.solver = app.SolverDropDown_2.Value;
            
        end

        % Value changed function: NoofLayersSpinner
        function NoofLayersSpinnerValueChanged(app, event)
            app.layers = app.NoofLayersSpinner.Value;
            
        end

        % Value changed function: PoolingtypeDropDown
        function PoolingtypeDropDownValueChanged(app, event)
            app.pooling = app.PoolingtypeDropDown.Value;
            
        end

        % Value changed function: SolverDropDown_3
        function SolverDropDown_3ValueChanged(app, event)
            app.solver = app.SolverDropDown_3.Value;
            
        end

        % Value changed function: NeuronsEditField_2
        function NeuronsEditField_2ValueChanged(app, event)
            app.neurons = str2num(app.NeuronsEditField_2.Value);
            
        end

        % Button pushed function: TrainnetworkButton
        function TrainnetworkButtonPushed(app, event)
            main_train(app.patientID, app.nn_type, app.n_features, app.optimization, app.delay,...
                app.training_style,app.training_option, app.activation_function, ...
                app.solver, app.epochs, app.layers, app.pooling, app.neurons, ...
                app.conv_nfilters, app.conv_filtersize, app.conv_stride, ...
                app.pooling_size, app.pooling_stride, app.step);
    
     
        end

        % Value changed function: NeuronsEditField_3
        function NeuronsEditField_3ValueChanged(app, event)
            value = app.NeuronsEditField_3.Value;
            
            value=string(value);
            strArray = split(value);
            app.neurons = [];
            
            for i=1:length(strArray)
                
                app.neurons=[app.neurons str2num(strArray(i))];
                
            end

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            msgbox(['Escolha o número de neurónios para cada hidden layer. Coloque estes por ordem e separados por espaços.'; ...
               "Exemplo:  3 layers, sendo que a 1ª tem 10 neurónios, a 2ª tem 5 e a 3ª tem 3. Fica: '10 5 3'."],'Help', 'help');
            % SNN
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            [app.file_name, app.path] = uigetfile('RedesTreinadasO');
            
            app.SelectednetworkEditField.Value=app.file_name;
        end

        % Button pushed function: TestnetworkButton
        function TestnetworkButtonPushed(app, event)
            [sens_pred_PP, spec_pred_PP, sens_det_PP, spec_det_PP, sens_pred_FT, spec_pred_FT, sens_det_FT, spec_det_FT, nn_typeB, seizures_detected, seizures_predicted, n_seizures] = main_test(app.file_name, app.path);
            
            
            handleArray=[app.SpecificityEditField_8, app.SpecificityEditField_8Label, ...
                app.SensitivityEditField_4, app.SensitivityEditField_4Label, app.SpecificityEditField_7,...
                app.SpecificityEditField_7Label, app.SensitivityEditField_3, app.SensitivityEditField_3Label,...
                app.Label_blue_18, app.PREDICTIONLabel_2, app.DETECTIONLabel_2, app.SeizuresdetectedEditField,...
                app.SeizuresdetectedEditFieldLabel, app.SeizurespredictedEditField, app.SeizurespredictedEditFieldLabel, ...
                app.NumberofseizuresEditField, app.Label_blue_22, app.NumberofseizuresEditFieldLabel];
            
            if isequal(nn_typeB, 'CNN')
                set(handleArray, 'Enable', 'off');
            else
                set(handleArray, 'Enable', 'on');
            end
            
            app.SensitivityEditField.Value=sens_pred_PP;
            app.SpecificityEditField_5.Value=spec_pred_PP;
            app.SensitivityEditField_2.Value=sens_det_PP;
            app.SpecificityEditField_6.Value=spec_det_PP;
            app.SensitivityEditField_3.Value=sens_pred_FT;
            app.SpecificityEditField_7.Value=spec_pred_FT;
            app.SensitivityEditField_4.Value=sens_det_FT;
            app.SpecificityEditField_8.Value=spec_det_FT;
            app.NumberofseizuresEditField.Value=n_seizures;
            app.SeizuresdetectedEditField.Value=seizures_detected;
            app.SeizurespredictedEditField.Value=seizures_predicted;
            
        end

        % Value changed function: LayersDelays1nEditField
        function LayersDelays1nEditFieldValueChanged(app, event)
            app.delay = str2num(app.LayersDelays1nEditField.Value);
            
        end

        % Value changed function: EpochsEditField
        function EpochsEditFieldValueChanged(app, event)
            app.epochs = str2num(app.EpochsEditField.Value);
            
        end

        % Value changed function: NooffiltersineachlayerEditField
        function NooffiltersineachlayerEditFieldValueChanged(app, event)
            value = str2num(app.NooffiltersineachlayerEditField.Value);
            
            value=string(value);
            strArray = split(value);
            app.conv_nfilters=[];
            
            for i=1:length(strArray)
                
                app.conv_nfilters=[app.conv_nfilters str2num(strArray(i))];
                
            end

            
        end

        % Value changed function: SizeoffiltersforeachlayerEditField
        function SizeoffiltersforeachlayerEditFieldValueChanged(app, event)
            value = app.SizeoffiltersforeachlayerEditField.Value;
            
            value=string(value);
            strArray = split(value);
            app.conv_filtersize=[];
            
            for i=1:length(strArray)
                
                app.conv_filtersize=[app.conv_filtersize str2num(strArray(i))];
                
            end

        end

        % Value changed function: EpochsEditField_2
        function EpochsEditField_2ValueChanged(app, event)
            app.epochs = str2num(app.EpochsEditField_2.Value);
            
        end

        % Button pushed function: HelpButton_2
        function HelpButton_2Pushed(app, event)
            msgbox(["Escolha o número de filtros, o tamanho destes e o valor stride para cada layer! Coloque por ordem e separados por espaços."; "   "; ...
                "EXEMPLO: 3 layers, sendo que a 1ª tem 10 filtros, a 2ª tem 5 e a 3ª tem 3. Fica: '10 5 3'."; ...
                "O mesmo raciocínio se aplica para o tamanho e valor stride."], 'Help', 'help'); 
            % CNN - Convolutional
        end

        % Button pushed function: INFOButton
        function INFOButtonPushed(app, event)
            msgbox(["TRAIN"; "No separador Train, deve ser escolhido o paciente e os parâmetros da rede. " + ...
                "Após o preenchimento de todos os campos basta carregar no botão 'Train network' para que a rede neuronal com as especificações desejadas seja treinada."; ...
                "------------------------------------------------------------------------";
                "TEST"; "No separador Test, é possível testar redes neuronais já treinadas." + ...
                " Para tal, deve carregar no botão 'Search' e escolher um dos ficheiros e, de seguida, carregar no botão 'Test'. " + ...
                "Serão presentados os resultados obtidos da sensibilidade e especificidade para a deteção e previsão nos dois métodos."; ...
                "------------------------------------------------------------------------"; ...
                "CLUSTERING"; "Neste separador, pode escolher o paciente e o algoritmo de clustering que pretende utilizar. " + ...
                "Para visualizar os gráficos 3D com os dados reduzidos e os clusters é necessário carregar no botão 'Plot'."],'Help', 'help');
            % MAIN
        end

        % Value changed function: PreprocessingstepDropDown
        function PreprocessingstepDropDownValueChanged(app, event)
            app.step = str2num(app.PreprocessingstepDropDown.Value);
            
        end

        % Button pushed function: HelpButton_4
        function HelpButton_4Pushed(app, event)
            msgbox(['Nas redes neuronais CNN é possível escolher entre a divisão dos dados ser em janelas de 29, de 29 em 29 segundos, ou seja, sem sobreposição, '...
                'ou de 15 em 15 segundos, ou seja, com sobreposição de cerca de 50%, permitindo um maior dataset.'],'Help', 'help');
        end

        % Value changed function: ClusteringalgorithmDropDown
        function ClusteringalgorithmDropDownValueChanged(app, event)
            app.clustering_algorithm = app.ClusteringalgorithmDropDown.Value;
            
            handleArrayDBSCAN = [app.EpsilonEditField, app.EpsilonEditFieldLabel, app.MinimumPointsEditField, app.MinimumPointsEditFieldLabel];
            
            if isequal(app.clustering_algorithm, 'K-means')     
                set(handleArrayDBSCAN, 'Visible', 'off');
            else
                set(handleArrayDBSCAN, 'Visible', 'on');               
            end
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            [P, ind_interictal, ind_preictal, ind_ictal, ind_1, ind_2, ind_3] = clustering(app.patientID, app.clustering_algorithm, app.min_points, app.epsilon);
          
            % Gráfico das features reduzidas
            scatter3(app.GraphReducedFeatures, P(ind_interictal, 1), P(ind_interictal, 2), P(ind_interictal, 3), 'go'); hold(app.GraphReducedFeatures);
            scatter3(app.GraphReducedFeatures, P(ind_preictal, 1), P(ind_preictal, 2), P(ind_preictal, 3), 'ro')
            scatter3(app.GraphReducedFeatures, P(ind_ictal, 1), P(ind_ictal, 2), P(ind_ictal, 3), 'b') 
            
            hold(app.GraphReducedFeatures, "off")
            legend(app.GraphReducedFeatures, 'Interictal','Preictal', 'Ictal')
            xlabel(app.GraphReducedFeatures, 'Feature 1'); ylabel(app.GraphReducedFeatures, 'Feature 2'); zlabel(app.GraphReducedFeatures, 'Feature 3')
            legend(app.GraphReducedFeatures, 'Interictal','Preictal', 'Ictal')
            title(app.GraphReducedFeatures, 'Reduced data') 
            
            % Gráfico das clusters
            scatter3(app.GraphClusters, P(ind_1, 1), P(ind_1, 2), P(ind_1, 3), 'go'); hold(app.GraphClusters) 
            scatter3(app.GraphClusters, P(ind_2, 1), P(ind_2, 2), P(ind_2, 3), 'ro');
            scatter3(app.GraphClusters, P(ind_3, 1), P(ind_3, 2), P(ind_3, 3), 'bo');
        
            hold(app.GraphClusters, "off") 
            xlabel(app.GraphClusters,'Feature 1'); ylabel(app.GraphClusters,'Feature 2'); zlabel(app.GraphClusters,'Feature 3');
            legend(app.GraphClusters,'Cluster 1','Cluster 2', 'Cluster 3');
            
            if isequal(app.clustering_algorithm, "DBSCAN")
               title(app.GraphClusters, 'Clustering DBSCAN');
                
            elseif isequal(app.clustering_algorithm, "K-means")
               title(app.GraphClusters, 'Clustering K-means');              
            end
              
        end

        % Value changed function: EpsilonEditField
        function EpsilonEditFieldValueChanged(app, event)
            app.epsilon = str2num(app.EpsilonEditField.Value);
            
        end

        % Value changed function: MinimumPointsEditField
        function MinimumPointsEditFieldValueChanged(app, event)
             app.min_points = str2num(app.MinimumPointsEditField.Value);
            
        end

        % Value changed function: PatientIDDropDown_2
        function PatientIDDropDown_2ValueChanged(app, event)
            app.patientID = app.PatientIDDropDown_2.Value; % Paciente escolhido
            
        end

        % Button pushed function: HelpButton_5
        function HelpButton_5Pushed(app, event)
            msgbox(["Escolha o tipo de processo Pooling, o tamanho e o stride para cada layer! Coloque por ordem e separados por espaços."; "   "; ...
                "EXEMPLO: 3 layers, sendo que o tamanho da layer pooling é 2 na 1ª layer, 3 na 2ª e 4 na 3ª. Fica: '2 3 4'."; ...
                "O mesmo raciocínio se aplica para o valor stride."], 'Help', 'help'); 
            % CNN - Pooling
        end

        % Value changed function: StrideforeachlayerEditField
        function StrideforeachlayerEditFieldValueChanged(app, event)
            value = app.StrideforeachlayerEditField.Value;
            
            value=string(value);
            strArray = split(value);
            app.conv_stride=[];
            
            for i=1:length(strArray)
                
                app.conv_stride=[app.conv_stride str2num(strArray(i))];
                
            end

        end

        % Value changed function: SizeforeachlayerEditField
        function SizeforeachlayerEditFieldValueChanged(app, event)
            value = app.SizeforeachlayerEditField.Value;
            
            value=string(value);
            strArray = split(value);
            app.pooling_size=[];
            
            for i=1:length(strArray)
                
                app.pooling_size=[app.pooling_size str2num(strArray(i))];
                
            end
        end

        % Value changed function: StrideforeachlayerEditField_2
        function StrideforeachlayerEditField_2ValueChanged(app, event)
            value = app.StrideforeachlayerEditField_2.Value;
            
            value=string(value);
            strArray = split(value);
            app.pooling_stride=[];
            
            for i=1:length(strArray)
                
                app.pooling_stride=[app.pooling_stride str2num(strArray(i))];
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MAINTab = uitab(app.TabGroup);
            app.MAINTab.Title = 'MAIN';
            app.Label_3 = uilabel(app.MAINTab);
            app.Label_3.Text = '______________________________';
            app.PredictionanddetectionofepilepticseizuresLabel_3 = uilabel(app.MAINTab);
            app.PredictionanddetectionofepilepticseizuresLabel_3.Text = {'Prediction and detection '; 'of epileptic seizures'};
            app.Label_4 = uilabel(app.MAINTab);
            app.Label_4.Text = {'This app will allow you to train and test Neural Networks '; 'with datasets from patients with epilepsy'};
            app.INFOButton = uibutton(app.MAINTab, 'push');
            app.INFOButton.ButtonPushedFcn = createCallbackFcn(app, @INFOButtonPushed, true);
            app.INFOButton.Text = '+ INFO';
            app.Label_blue_27 = uilabel(app.MAINTab);
            app.Label_blue_27.Text = '';
            app.ComputaoNeuronaleSistemasDifusos202021Label_5 = uilabel(app.MAINTab);
            app.ComputaoNeuronaleSistemasDifusos202021Label_5.Text = 'Computação Neuronal e Sistemas Difusos - 2020/21';
            app.PL4Grupo3AndrBernardesJoanaBaioLabel_6 = uilabel(app.MAINTab);
            app.PL4Grupo3AndrBernardesJoanaBaioLabel_6.Text = 'PL4 - Grupo 3 - André Bernardes & Joana Baião';
            app.Train = uitab(app.TabGroup);
            app.Train.Title = 'TRAIN';
            app.Label_blue_4 = uilabel(app.Train);
            app.Label_blue_4.Text = '';
            app.Label_blue_29 = uilabel(app.Train);
            app.Label_blue_29.Text = '';
            app.Label_blue_30 = uilabel(app.Train);
            app.Label_blue_30.Text = '';
            app.Label_blue_2 = uilabel(app.Train);
            app.Label_blue_2.Text = '';
            app.Label_blue_28 = uilabel(app.Train);
            app.Label_blue_28.Text = '';
            app.Label_blue_3 = uilabel(app.Train);
            app.Label_blue_3.Text = 'SHALLOW NEURAL NETWORK (SNN)';
            app.TrainnetworkButton = uibutton(app.Train, 'push');
            app.TrainnetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainnetworkButtonPushed, true);
            app.TrainnetworkButton.Text = 'Train network';
            app.Label_blue_7 = uilabel(app.Train);
            app.Label_blue_7.Enable = 'off';
            app.Label_blue_7.Text = 'LONG SHORT-TERM MEMORY (LSTM)';
            app.Label_blue_8 = uilabel(app.Train);
            app.Label_blue_8.Text = 'GENERAL OPTIONS';
            app.Label_blue_9 = uilabel(app.Train);
            app.Label_blue_9.Text = '';
            app.PatientIDDropDownLabel = uilabel(app.Train);
            app.PatientIDDropDownLabel.Text = 'Patient ID';
            app.PatientIDDropDown = uidropdown(app.Train);
            app.PatientIDDropDown.Items = {'44202', '63502', '54802', '112502'};
            app.PatientIDDropDown.ValueChangedFcn = createCallbackFcn(app, @PatientIDDropDownValueChanged, true);
            app.PatientIDDropDown.Value = '44202';
            app.NetworktypeDropDownLabel = uilabel(app.Train);
            app.NetworktypeDropDownLabel.Text = 'Network type';
            app.NetworktypeDropDown = uidropdown(app.Train);
            app.NetworktypeDropDown.Items = {'SNN', 'DNN: LSTM', 'DNN: CNN'};
            app.NetworktypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworktypeDropDownValueChanged, true);
            app.NetworktypeDropDown.Value = 'SNN';
            app.FeaturesDropDownLabel = uilabel(app.Train);
            app.FeaturesDropDownLabel.Text = 'Features';
            app.FeaturesDropDown = uidropdown(app.Train);
            app.FeaturesDropDown.Items = {'29', '15', '10', '3'};
            app.FeaturesDropDown.ValueChangedFcn = createCallbackFcn(app, @FeaturesDropDownValueChanged, true);
            app.FeaturesDropDown.Value = '29';
            app.OptimizationDropDownLabel = uilabel(app.Train);
            app.OptimizationDropDownLabel.Text = 'Optimization';
            app.OptimizationDropDown = uidropdown(app.Train);
            app.OptimizationDropDown.Items = {'None', 'Parallel', 'GPU', 'GPU + Parallel'};
            app.OptimizationDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationDropDownValueChanged, true);
            app.OptimizationDropDown.Value = 'None';
            app.TrainingstyleLabel = uilabel(app.Train);
            app.TrainingstyleLabel.Text = {'Training'; 'style'};
            app.TrainingstyleDropDown = uidropdown(app.Train);
            app.TrainingstyleDropDown.Items = {'Batch training', 'Incremental learning'};
            app.TrainingstyleDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainingstyleDropDownValueChanged, true);
            app.TrainingstyleDropDown.Value = 'Batch training';
            app.ActivationfunctionLabel = uilabel(app.Train);
            app.ActivationfunctionLabel.Text = {'Activation'; ' function'};
            app.ActivationfunctionDropDown = uidropdown(app.Train);
            app.ActivationfunctionDropDown.Items = {'purelin', 'tansig', 'logsig'};
            app.ActivationfunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @ActivationfunctionDropDownValueChanged, true);
            app.ActivationfunctionDropDown.Value = 'purelin';
            app.NetworkarchitectureLabel = uilabel(app.Train);
            app.NetworkarchitectureLabel.Text = {'Network '; 'architecture'};
            app.NetworkarchitectureDropDown = uidropdown(app.Train);
            app.NetworkarchitectureDropDown.Items = {'Recurrent', 'Feedforward'};
            app.NetworkarchitectureDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworkarchitectureDropDownValueChanged, true);
            app.NetworkarchitectureDropDown.Value = 'Recurrent';
            app.NoofhiddenlayersSpinnerLabel = uilabel(app.Train);
            app.NoofhiddenlayersSpinnerLabel.Text = {'No. of '; 'hidden layers'};
            app.NoofhiddenlayersSpinner = uispinner(app.Train);
            app.NoofhiddenlayersSpinner.Limits = [1 5];
            app.NoofhiddenlayersSpinner.ValueChangedFcn = createCallbackFcn(app, @NoofhiddenlayersSpinnerValueChanged, true);
            app.NoofhiddenlayersSpinner.Value = 1;
            app.LearningfunctionLabel = uilabel(app.Train);
            app.LearningfunctionLabel.Visible = 'off';
            app.LearningfunctionLabel.Text = {'Learning '; 'function'};
            app.LearningfunctionDropDown = uidropdown(app.Train);
            app.LearningfunctionDropDown.Items = {'learngd', 'learngdm', 'learnh', 'learnhd'};
            app.LearningfunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @LearningfunctionDropDownValueChanged, true);
            app.LearningfunctionDropDown.Visible = 'off';
            app.LearningfunctionDropDown.Value = 'learngd';
            app.TrainfunctionDropDownLabel = uilabel(app.Train);
            app.TrainfunctionDropDownLabel.Text = 'Train function';
            app.TrainfunctionDropDown = uidropdown(app.Train);
            app.TrainfunctionDropDown.Items = {'traingd', 'traingda', 'traingdm', 'trainlm', 'trainscg'};
            app.TrainfunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainfunctionDropDownValueChanged, true);
            app.TrainfunctionDropDown.Value = 'traingd';
            app.Label_blue_10 = uilabel(app.Train);
            app.Label_blue_10.Text = '';
            app.SolverDropDown_3Label = uilabel(app.Train);
            app.SolverDropDown_3Label.Enable = 'off';
            app.SolverDropDown_3Label.Text = 'Solver';
            app.SolverDropDown_3 = uidropdown(app.Train);
            app.SolverDropDown_3.Items = {'adam', 'sgdm', 'rmsprop'};
            app.SolverDropDown_3.ValueChangedFcn = createCallbackFcn(app, @SolverDropDown_3ValueChanged, true);
            app.SolverDropDown_3.Enable = 'off';
            app.SolverDropDown_3.Value = 'adam';
            app.NeuronsEditField_2Label = uilabel(app.Train);
            app.NeuronsEditField_2Label.Enable = 'off';
            app.NeuronsEditField_2Label.Text = 'Neurons';
            app.NeuronsEditField_2 = uieditfield(app.Train, 'text');
            app.NeuronsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NeuronsEditField_2ValueChanged, true);
            app.NeuronsEditField_2.Enable = 'off';
            app.NeuronsEditField_3Label = uilabel(app.Train);
            app.NeuronsEditField_3Label.Text = 'Neurons';
            app.NeuronsEditField_3 = uieditfield(app.Train, 'text');
            app.NeuronsEditField_3.ValueChangedFcn = createCallbackFcn(app, @NeuronsEditField_3ValueChanged, true);
            app.HelpButton = uibutton(app.Train, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.EpochsEditFieldLabel = uilabel(app.Train);
            app.EpochsEditFieldLabel.Enable = 'off';
            app.EpochsEditFieldLabel.Text = 'Epochs';
            app.EpochsEditField = uieditfield(app.Train, 'text');
            app.EpochsEditField.ValueChangedFcn = createCallbackFcn(app, @EpochsEditFieldValueChanged, true);
            app.EpochsEditField.Enable = 'off';
            app.EpochsEditField_2Label = uilabel(app.Train);
            app.EpochsEditField_2Label.Enable = 'off';
            app.EpochsEditField_2Label.Text = 'Epochs';
            app.EpochsEditField_2 = uieditfield(app.Train, 'text');
            app.EpochsEditField_2.ValueChangedFcn = createCallbackFcn(app, @EpochsEditField_2ValueChanged, true);
            app.EpochsEditField_2.Enable = 'off';
            app.NooffiltersineachlayerEditFieldLabel = uilabel(app.Train);
            app.NooffiltersineachlayerEditFieldLabel.Enable = 'off';
            app.NooffiltersineachlayerEditFieldLabel.Text = {'No. of filters'; 'in each layer'};
            app.NooffiltersineachlayerEditField = uieditfield(app.Train, 'text');
            app.NooffiltersineachlayerEditField.ValueChangedFcn = createCallbackFcn(app, @NooffiltersineachlayerEditFieldValueChanged, true);
            app.NooffiltersineachlayerEditField.Enable = 'off';
            app.SizeoffiltersforeachlayerEditFieldLabel = uilabel(app.Train);
            app.SizeoffiltersforeachlayerEditFieldLabel.Enable = 'off';
            app.SizeoffiltersforeachlayerEditFieldLabel.Text = {'Size of filters'; 'for each layer'};
            app.SizeoffiltersforeachlayerEditField = uieditfield(app.Train, 'text');
            app.SizeoffiltersforeachlayerEditField.ValueChangedFcn = createCallbackFcn(app, @SizeoffiltersforeachlayerEditFieldValueChanged, true);
            app.SizeoffiltersforeachlayerEditField.Enable = 'off';
            app.LayersDelays1nLabel = uilabel(app.Train);
            app.LayersDelays1nLabel.Text = {'Layers Delays'; ' (1: n)'};
            app.LayersDelays1nEditField = uieditfield(app.Train, 'text');
            app.LayersDelays1nEditField.ValueChangedFcn = createCallbackFcn(app, @LayersDelays1nEditFieldValueChanged, true);
            app.HelpButton_2 = uibutton(app.Train, 'push');
            app.HelpButton_2.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_2Pushed, true);
            app.HelpButton_2.Enable = 'off';
            app.HelpButton_2.Text = 'Help';
            app.PreprocessingstepDropDownLabel = uilabel(app.Train);
            app.PreprocessingstepDropDownLabel.Enable = 'off';
            app.PreprocessingstepDropDownLabel.Text = {'Preprocessing'; 'step'};
            app.PreprocessingstepDropDown = uidropdown(app.Train);
            app.PreprocessingstepDropDown.Items = {'29', '15'};
            app.PreprocessingstepDropDown.ValueChangedFcn = createCallbackFcn(app, @PreprocessingstepDropDownValueChanged, true);
            app.PreprocessingstepDropDown.Enable = 'off';
            app.PreprocessingstepDropDown.Value = '29';
            app.HelpButton_4 = uibutton(app.Train, 'push');
            app.HelpButton_4.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_4Pushed, true);
            app.HelpButton_4.Enable = 'off';
            app.HelpButton_4.Text = 'Help';
            app.Label_blue_5 = uilabel(app.Train);
            app.Label_blue_5.Enable = 'off';
            app.Label_blue_5.Text = 'CONVOLUTIONAL NEURAL NETWORK (CNN)';
            app.SolverDropDown_2Label = uilabel(app.Train);
            app.SolverDropDown_2Label.Enable = 'off';
            app.SolverDropDown_2Label.Text = 'Solver';
            app.SolverDropDown_2 = uidropdown(app.Train);
            app.SolverDropDown_2.Items = {'adam', 'sgdm', 'rmsprop'};
            app.SolverDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SolverDropDown_2ValueChanged, true);
            app.SolverDropDown_2.Enable = 'off';
            app.SolverDropDown_2.Value = 'adam';
            app.NoofLayersSpinnerLabel = uilabel(app.Train);
            app.NoofLayersSpinnerLabel.Enable = 'off';
            app.NoofLayersSpinnerLabel.Text = 'No. of Layers';
            app.NoofLayersSpinner = uispinner(app.Train);
            app.NoofLayersSpinner.Limits = [1 3];
            app.NoofLayersSpinner.ValueChangedFcn = createCallbackFcn(app, @NoofLayersSpinnerValueChanged, true);
            app.NoofLayersSpinner.Enable = 'off';
            app.NoofLayersSpinner.Value = 1;
            app.PoolingtypeDropDownLabel = uilabel(app.Train);
            app.PoolingtypeDropDownLabel.Enable = 'off';
            app.PoolingtypeDropDownLabel.Text = 'Pooling type';
            app.PoolingtypeDropDown = uidropdown(app.Train);
            app.PoolingtypeDropDown.Items = {'max', 'avg'};
            app.PoolingtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PoolingtypeDropDownValueChanged, true);
            app.PoolingtypeDropDown.Enable = 'off';
            app.PoolingtypeDropDown.Value = 'max';
            app.HIDDENLAYERSLabel = uilabel(app.Train);
            app.HIDDENLAYERSLabel.Text = 'HIDDEN LAYERS';
            app.CONVOLUTIONALLAYERSLabel = uilabel(app.Train);
            app.CONVOLUTIONALLAYERSLabel.Enable = 'off';
            app.CONVOLUTIONALLAYERSLabel.Text = 'CONVOLUTIONAL LAYERS';
            app.StrideforeachlayerEditFieldLabel = uilabel(app.Train);
            app.StrideforeachlayerEditFieldLabel.Enable = 'off';
            app.StrideforeachlayerEditFieldLabel.Text = {'Stride for'; ' each layer'};
            app.StrideforeachlayerEditField = uieditfield(app.Train, 'text');
            app.StrideforeachlayerEditField.ValueChangedFcn = createCallbackFcn(app, @StrideforeachlayerEditFieldValueChanged, true);
            app.StrideforeachlayerEditField.Enable = 'off';
            app.POOLINGLAYERSLabel = uilabel(app.Train);
            app.POOLINGLAYERSLabel.Enable = 'off';
            app.POOLINGLAYERSLabel.Text = 'POOLING LAYERS';
            app.SizeforeachlayerLabel = uilabel(app.Train);
            app.SizeforeachlayerLabel.Enable = 'off';
            app.SizeforeachlayerLabel.Text = {'Size for '; 'each layer'};
            app.SizeforeachlayerEditField = uieditfield(app.Train, 'text');
            app.SizeforeachlayerEditField.ValueChangedFcn = createCallbackFcn(app, @SizeforeachlayerEditFieldValueChanged, true);
            app.SizeforeachlayerEditField.Enable = 'off';
            app.StrideforeachlayerEditField_2Label = uilabel(app.Train);
            app.StrideforeachlayerEditField_2Label.Enable = 'off';
            app.StrideforeachlayerEditField_2Label.Text = {'Stride for'; ' each layer'};
            app.StrideforeachlayerEditField_2 = uieditfield(app.Train, 'text');
            app.StrideforeachlayerEditField_2.ValueChangedFcn = createCallbackFcn(app, @StrideforeachlayerEditField_2ValueChanged, true);
            app.StrideforeachlayerEditField_2.Enable = 'off';
            app.HelpButton_5 = uibutton(app.Train, 'push');
            app.HelpButton_5.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_5Pushed, true);
            app.HelpButton_5.Enable = 'off';
            app.HelpButton_5.Text = 'Help';
            app.Label_7 = uilabel(app.Train);
            app.Label_7.Text = '_________________________________';
            app.PredictionanddetectionofepilepticseizuresLabel_6 = uilabel(app.Train);
            app.PredictionanddetectionofepilepticseizuresLabel_6.Text = 'Prediction and detection of epileptic seizures';
            app.TESTTab = uitab(app.TabGroup);
            app.TESTTab.Title = 'TEST';
            app.Label_blue_11 = uilabel(app.TESTTab);
            app.Label_blue_11.Text = 'SELECT NETWORK';
            app.Label_blue_12 = uilabel(app.TESTTab);
            app.Label_blue_12.Text = '';
            app.SearchButton = uibutton(app.TESTTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.TestnetworkButton = uibutton(app.TESTTab, 'push');
            app.TestnetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TestnetworkButtonPushed, true);
            app.TestnetworkButton.Text = 'Test network';
            app.Label_blue_13 = uilabel(app.TESTTab);
            app.Label_blue_13.Text = '';
            app.Label_blue_14 = uilabel(app.TESTTab);
            app.Label_blue_14.Text = 'RESULTS: POINT BY POINT';
            app.PREDICTIONLabel = uilabel(app.TESTTab);
            app.PREDICTIONLabel.Text = 'PREDICTION';
            app.DETECTIONLabel = uilabel(app.TESTTab);
            app.DETECTIONLabel.Text = 'DETECTION';
            app.SensitivityEditFieldLabel = uilabel(app.TESTTab);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity (%)';
            app.SensitivityEditField = uieditfield(app.TESTTab, 'numeric');
            app.SensitivityEditField.Editable = 'off';
            app.Label_blue_16 = uilabel(app.TESTTab);
            app.Label_blue_16.Text = '';
            app.Label_blue_17 = uilabel(app.TESTTab);
            app.Label_blue_17.Text = '';
            app.Label_blue_18 = uilabel(app.TESTTab);
            app.Label_blue_18.Text = 'RESULTS: BLOCK OF 5 IN 10';
            app.PREDICTIONLabel_2 = uilabel(app.TESTTab);
            app.PREDICTIONLabel_2.Text = 'PREDICTION';
            app.DETECTIONLabel_2 = uilabel(app.TESTTab);
            app.DETECTIONLabel_2.Text = 'DETECTION';
            app.Label_blue_19 = uilabel(app.TESTTab);
            app.Label_blue_19.Text = '';
            app.SpecificityEditField_5Label = uilabel(app.TESTTab);
            app.SpecificityEditField_5Label.Text = 'Specificity (%)';
            app.SpecificityEditField_5 = uieditfield(app.TESTTab, 'numeric');
            app.SpecificityEditField_5.Editable = 'off';
            app.SensitivityEditField_2Label = uilabel(app.TESTTab);
            app.SensitivityEditField_2Label.Text = 'Sensitivity (%)';
            app.SensitivityEditField_2 = uieditfield(app.TESTTab, 'numeric');
            app.SensitivityEditField_2.Editable = 'off';
            app.SpecificityEditField_6Label = uilabel(app.TESTTab);
            app.SpecificityEditField_6Label.Text = 'Specificity (%)';
            app.SpecificityEditField_6 = uieditfield(app.TESTTab, 'numeric');
            app.SpecificityEditField_6.Editable = 'off';
            app.SensitivityEditField_3Label = uilabel(app.TESTTab);
            app.SensitivityEditField_3Label.Text = 'Sensitivity (%)';
            app.SensitivityEditField_3 = uieditfield(app.TESTTab, 'numeric');
            app.SensitivityEditField_3.Editable = 'off';
            app.SpecificityEditField_7Label = uilabel(app.TESTTab);
            app.SpecificityEditField_7Label.Text = 'Specificity (%)';
            app.SpecificityEditField_7 = uieditfield(app.TESTTab, 'numeric');
            app.SpecificityEditField_7.Editable = 'off';
            app.SensitivityEditField_4Label = uilabel(app.TESTTab);
            app.SensitivityEditField_4Label.Text = 'Sensitivity (%)';
            app.SensitivityEditField_4 = uieditfield(app.TESTTab, 'numeric');
            app.SensitivityEditField_4.Editable = 'off';
            app.SpecificityEditField_8Label = uilabel(app.TESTTab);
            app.SpecificityEditField_8Label.Text = 'Specificity (%)';
            app.SpecificityEditField_8 = uieditfield(app.TESTTab, 'numeric');
            app.SpecificityEditField_8.Editable = 'off';
            app.SelectednetworkLabel = uilabel(app.TESTTab);
            app.SelectednetworkLabel.Text = 'Selected network:';
            app.SelectednetworkEditField = uieditfield(app.TESTTab, 'text');
            app.Label_blue_21 = uilabel(app.TESTTab);
            app.Label_blue_21.Text = '';
            app.Label_blue_22 = uilabel(app.TESTTab);
            app.Label_blue_22.Text = 'RESULTS: RESUME';
            app.NumberofseizuresEditFieldLabel = uilabel(app.TESTTab);
            app.NumberofseizuresEditFieldLabel.Text = 'Number of seizures';
            app.NumberofseizuresEditField = uieditfield(app.TESTTab, 'numeric');
            app.NumberofseizuresEditField.Editable = 'off';
            app.SeizurespredictedEditFieldLabel = uilabel(app.TESTTab);
            app.SeizurespredictedEditFieldLabel.Text = 'Seizures predicted';
            app.SeizurespredictedEditField = uieditfield(app.TESTTab, 'numeric');
            app.SeizurespredictedEditField.Editable = 'off';
            app.SeizuresdetectedEditFieldLabel = uilabel(app.TESTTab);
            app.SeizuresdetectedEditFieldLabel.Text = 'Seizures detected';
            app.SeizuresdetectedEditField = uieditfield(app.TESTTab, 'numeric');
            app.SeizuresdetectedEditField.Editable = 'off';
            app.Label_6 = uilabel(app.TESTTab);
            app.Label_6.Text = '_________________________________';
            app.PredictionanddetectionofepilepticseizuresLabel_5 = uilabel(app.TESTTab);
            app.PredictionanddetectionofepilepticseizuresLabel_5.Text = 'Prediction and detection of epileptic seizures';
            app.CLUSTERINGTab = uitab(app.TabGroup);
            app.CLUSTERINGTab.Title = 'CLUSTERING';
            app.GraphReducedFeatures = uiaxes(app.CLUSTERINGTab);
            title(app.GraphReducedFeatures, 'Reduced data')
            xlabel(app.GraphReducedFeatures, 'X')
            ylabel(app.GraphReducedFeatures, 'Y')
            app.GraphClusters = uiaxes(app.CLUSTERINGTab);
            title(app.GraphClusters, 'Clustering')
            xlabel(app.GraphClusters, 'X')
            ylabel(app.GraphClusters, 'Y')
            app.Label_5 = uilabel(app.CLUSTERINGTab);
            app.Label_5.Text = '_________________________________';
            app.PredictionanddetectionofepilepticseizuresLabel_4 = uilabel(app.CLUSTERINGTab);
            app.PredictionanddetectionofepilepticseizuresLabel_4.Text = 'Prediction and detection of epileptic seizures';
            app.Label_blue_25 = uilabel(app.CLUSTERINGTab);
            app.Label_blue_25.Text = 'CLUSTERING OPTIONS';
            app.Label_blue_26 = uilabel(app.CLUSTERINGTab);
            app.Label_blue_26.Text = '';
            app.PlotButton = uibutton(app.CLUSTERINGTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClusteringalgorithmDropDownLabel = uilabel(app.CLUSTERINGTab);
            app.ClusteringalgorithmDropDownLabel.Text = 'Clustering algorithm';
            app.ClusteringalgorithmDropDown = uidropdown(app.CLUSTERINGTab);
            app.ClusteringalgorithmDropDown.Items = {'DBSCAN', 'K-means'};
            app.ClusteringalgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @ClusteringalgorithmDropDownValueChanged, true);
            app.ClusteringalgorithmDropDown.Value = 'DBSCAN';
            app.PatientIDDropDown_2Label = uilabel(app.CLUSTERINGTab);
            app.PatientIDDropDown_2Label.Text = 'Patient ID';
            app.PatientIDDropDown_2 = uidropdown(app.CLUSTERINGTab);
            app.PatientIDDropDown_2.Items = {'44202', '63502', '54802', '112502', '', ''};
            app.PatientIDDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PatientIDDropDown_2ValueChanged, true);
            app.PatientIDDropDown_2.Value = '44202';
            app.EpsilonEditFieldLabel = uilabel(app.CLUSTERINGTab);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.CLUSTERINGTab, 'text');
            app.EpsilonEditField.ValueChangedFcn = createCallbackFcn(app, @EpsilonEditFieldValueChanged, true);
            app.MinimumPointsEditFieldLabel = uilabel(app.CLUSTERINGTab);
            app.MinimumPointsEditFieldLabel.Text = 'Minimum Points';
            app.MinimumPointsEditField = uieditfield(app.CLUSTERINGTab, 'text');
            app.MinimumPointsEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumPointsEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end